classdef enhancements < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ContrastStretchingButton     matlab.ui.control.Button
        HistogramEqualizationButton  matlab.ui.control.Button
        PowerLawTransformButton      matlab.ui.control.Button
        LogTransformButton           matlab.ui.control.Button
        NegativeTransformButton      matlab.ui.control.Button
        OutputImageLabel             matlab.ui.control.Label
        InputImageLabel              matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        EnhancementsLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Button pushed function: NegativeTransformButton
        function NegativeTransformButtonPushed(app, event)
            positiveImage = imread(app.Image.ImageSource);
            negativeImage = 255 - positiveImage;
            app.Image_2.ImageSource = negativeImage;
        end

        % Button pushed function: LogTransformButton
        function LogTransformButtonPushed(app, event)
            Img = imread(app.Image.ImageSource); % Read the image
            Img2 = double(Img)/255; % Normalized Image
            c = 2; % Constant
            ImgLT = c*log(1 + (Img2)); % Log Transform
            app.Image_2.ImageSource = ImgLT;
        end

        % Button pushed function: PowerLawTransformButton
        function PowerLawTransformButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            J = imadjust(I,[],[],0.5);
            app.Image_2.ImageSource = J;
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            J = histeq(I);
            app.Image_2.ImageSource = J;
            figure,
            subplot(1,2,1),imhist(I,64); title('Original Image Histogram')
            subplot(1,2,2),imhist(J,64);title('Equalized Histogram')
        end

        % Button pushed function: ContrastStretchingButton
        function ContrastStretchingButtonPushed(app, event)
            X = imread(app.Image.ImageSource); %reading a grayscale image
            a = min(X(:));  %minimum pixel of image X
            b = max(X(:)); %maximum pixel of image X
            Y = (X-a).*(255/(b-a)); %just using the formula above
            app.Image_2.ImageSource = Y;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnhancementsLabel = uilabel(app.UIFigure);
            app.EnhancementsLabel.Text = 'Enhancements';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'car1.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Text = 'Output Image';
            app.NegativeTransformButton = uibutton(app.UIFigure, 'push');
            app.NegativeTransformButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeTransformButtonPushed, true);
            app.NegativeTransformButton.Text = 'Negative Transform';
            app.LogTransformButton = uibutton(app.UIFigure, 'push');
            app.LogTransformButton.ButtonPushedFcn = createCallbackFcn(app, @LogTransformButtonPushed, true);
            app.LogTransformButton.Text = 'Log Transform';
            app.PowerLawTransformButton = uibutton(app.UIFigure, 'push');
            app.PowerLawTransformButton.ButtonPushedFcn = createCallbackFcn(app, @PowerLawTransformButtonPushed, true);
            app.PowerLawTransformButton.Text = 'Power Law Transform';
            app.HistogramEqualizationButton = uibutton(app.UIFigure, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.ContrastStretchingButton = uibutton(app.UIFigure, 'push');
            app.ContrastStretchingButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastStretchingButtonPushed, true);
            app.ContrastStretchingButton.Text = 'Contrast Stretching';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enhancements

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end