classdef filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ApplyFiltersButton         matlab.ui.control.Button
        SelectInputImageButton     matlab.ui.control.Button
        InputImageLabel            matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        SpatialDomainFiltersLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BoxFilterButtonPushed(app, event)

        end

        % Callback function
        function WeightedAverageFilterButtonPushed(app, event)

        end

        % Callback function
        function GaussianFilterButtonPushed(app, event)

        end

        % Callback function
        function MedianFilterButtonPushed(app, event)

        end

        % Callback function
        function LaplacianFilterButtonPushed(app, event)

        end

        % Callback function
        function SobelFilterButton_2Pushed(app, event)

        end

        % Button pushed function: SelectInputImageButton
        function SelectInputImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Button pushed function: ApplyFiltersButton
        function ApplyFiltersButtonPushed(app, event)
            I = imread(app.Image.ImageSource);
            g = rgb2gray(I);    % To convert RGB to Grayimage


            BoxFilter = conv2(single(g), ones(3)/9, 'same');
            WeightedAverageFilter = conv2(single(g), [1,2,1;2,4,2;1,2,1]/16, 'same');
            GaussianFilter = imgaussfilt(g,2); % with std dev=2, 'FilterSize’,3 (i.e. k)
            MedianFilter = ordfilt2(g,5,ones(3,3)); 

            
            figure,
            subplot(1,5,1),imshow(g, []);title('Orginal image')
            subplot(1,5,2),imshow(BoxFilter, []); title('Box filter image')
            subplot(1,5,3),imshow(WeightedAverageFilter, []); title('Weighted filter image')
            subplot(1,5,4),imshow(GaussianFilter, []); title('Gaussian filter image')
            subplot(1,5,5);imshow(MedianFilter); title("Median Filter image");


            %---------------------------Laplacian Filter (Highpass)--------------------------
            f = imread('moon.tif');
            %w4 = fspecial('laplacian',0);
            w4 = [0,1,0;1,-4,1;0,1,0];
            w8 = [1,1,1;1,-8,1;1,1,1];
            f=im2double(f);
            f4=imfilter(f,w4,'replicate');
            f8=imfilter(f,w8,'replicate');
            img4=f-f4; %org image minus w4 based filtered image
            img8=f-f8; %org image minus w8 based filtered image
            % Show all images
            figure;
            subplot(141),imshow(f); title('Original Image')
            subplot(142),imshow(f4);title('Laplacian filter -4')
            subplot(143),imshow(img4);title('Org image + Laplacian -4')
            subplot(144),imshow(img8);title('Org image + Laplacian -8')


            %---------------------------Sobel Filter (Highpass)--------------------------
            I = imread('moon.tif');
            w4 = [-1,-2,-1;0,0,0;1,2,1];
            w8 = [-1,0,1;-2,0,2;-1,0,1];
            I=im2double(I);
            Ix=imfilter(I,w4,'replicate');
            Iy=imfilter(I,w8,'replicate');
            I3 = edge(I,'sobel');
            figure
            subplot(141),imshow(I);  title('Orginal image');
            subplot(142),imshow(I3); title('Sobel Gradiant');
            subplot(143),imshow(Ix); title('x-direction Sobel');
            subplot(144),imshow(Iy); title('y-direction Sobel');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpatialDomainFiltersLabel = uilabel(app.UIFigure);
            app.SpatialDomainFiltersLabel.Text = 'Spatial Domain Filters';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'colorCheckerTestImage.jpg';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.SelectInputImageButton = uibutton(app.UIFigure, 'push');
            app.SelectInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputImageButtonPushed, true);
            app.SelectInputImageButton.Text = 'Select Input Image';
            app.ApplyFiltersButton = uibutton(app.UIFigure, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltersButtonPushed, true);
            app.ApplyFiltersButton.Text = 'Apply Filters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end