classdef freq_filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HighpassFiltersButton        matlab.ui.control.Button
        LowpassFiltersButton         matlab.ui.control.Button
        InputImageLabel              matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        FrequencyDomainFiltersLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Button pushed function: LowpassFiltersButton
        function LowpassFiltersButtonPushed(app, event)
            % Reading input image : input_image 
            %input_image = imread('[name of input image file].[file format]');
            input_image=rgb2gray(imread(app.Image.ImageSource));
            [M, N] = size(input_image);
            % using MATLAB library function fft2 (2D fast fourier transform)  
            FT_img = fft2(double(input_image));


            % MATLAB Code | Ideal Low Pass Filter
            % Assign Cut-off Frequency  
            D0 = 30; % one can change this value accordingly
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy 
            % of v, and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);
            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);
            % Comparing with the cut-off frequency and 
            % determining the filtering mask
            H = double(D <= D0);
            % Convolution between the Fourier Transformed
            % image and the mask
            G = H.*FT_img;
            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function 
            % ifft2 (2D inverse fast fourier transform)  
            output_image_I = real(ifft2(double(G)));


            % MATLAB Code | Butterworth Low Pass Filter
            % Assign the order value
            n = 2; % one can change this value accordingly
            % Assign Cut-off Frequency
            D0 = 20; % one can change this value accordingly
            % Designing filter
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy of v
            % and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);
            % Calculating Euclidean Distance
            D = sqrt(U.^2 + V.^2);
            % determining the filtering mask
            H = 1./(1 + (D./D0).^(2*n));
            % Convolution between the Fourier Transformed
            % image and the mask
            G = H.*FT_img;
            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)
            output_image_BW = real(ifft2(double(G)));


            % Displaying Input Image and Output Images
            figure,
            subplot(1, 3, 1), imshow(input_image), 
            title ('Original Image')
            subplot(1, 3, 2), imshow(output_image_I, [ ]);
            title ('IDEAL Lowpass filter')
            subplot(1, 3, 3), imshow(output_image_BW, [ ]);
            title ('BUTTERWORTH Lowpass filter')

        end

        % Button pushed function: HighpassFiltersButton
        function HighpassFiltersButtonPushed(app, event)
            % MATLAB Code | Ideal High Pass Filter  
            % Reading input image : input_image 
            input_image=rgb2gray(imread('football.jpg'));
            [M, N] = size(input_image);
            % Getting Fourier Transform of the input_image
            % using MATLAB library function fft2 (2D fast fourier transform)  
            FT_img = fft2(double(input_image));
            % Assign Cut-off Frequency  
            D0 = 10; % one can change this value accordingly
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;
            % MATLAB library function meshgrid(v, u) returns 2D grid
            %  which contains the coordinates of vectors v and u. 
            % Matrix V with each row is a copy of v, and matrix U 
            % with each column is a copy of u
            [V, U] = meshgrid(v, u);
            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);
            % Comparing with the cut-off frequency and 
            % determining the filtering mask
            H = double(D > D0);
            % Convolution between the Fourier Transformed image and the mask
            G = H.*FT_img;
            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)  
            output_image_I = real(ifft2(double(G)));


            % MATLAB Code | Butterworth High Pass Filter
            % Reading input image : input_image
            %input_image = imread('[name of input image file].[file format]');
            input_image=rgb2gray(imread('football.jpg'));
            %input_image = imnoise(org_image,'salt & pepper',0.1);
            % Saving the size of the input_image in pixels-
            % M : no of rows (height of the image)
            % N : no of columns (width of the image)
            [M, N] = size(input_image);
            % Getting Fourier Transform of the input_image
            % using MATLAB library function fft2 (2D fast fourier transform)
            FT_img = fft2(double(input_image));
            % Assign the order value
            n = 2; % one can change this value accordingly
            % Assign Cut-off Frequency
            D0 = 20; % one can change this value accordingly
            % Designing filter
            u = 0:(M-1);
            v = 0:(N-1);
            idx = find(u > M/2);
            u(idx) = u(idx) - M;
            idy = find(v > N/2);
            v(idy) = v(idy) - N;
            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy of v
            % and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);
            % Calculating Euclidean Distance
            D = sqrt(U.^2 + V.^2);
            % determining the filtering mask
            H = 1./(1 + (D./D0).^(2*n));
            % Convolution between the Fourier Transformed
            % image and the mask
            G = H.*(1-FT_img);
            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)
            output_image_BW = real(ifft2(double(G)));
            

            % Displaying Input Image and Output Image
            subplot(1, 3, 1), imshow(input_image), 
            title ('Orginal Image')
            subplot(1, 3, 2), imshow(output_image_I, [ ]);
            title ('IDEAL Highpass filter')
            subplot(1, 3, 3), imshow(output_image_BW, [ ]);
            title ('BUTTERWORTH Highpass filter')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencyDomainFiltersLabel = uilabel(app.UIFigure);
            app.FrequencyDomainFiltersLabel.Text = 'Frequency Domain Filters';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'football.jpg';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.LowpassFiltersButton = uibutton(app.UIFigure, 'push');
            app.LowpassFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassFiltersButtonPushed, true);
            app.LowpassFiltersButton.Text = 'Lowpass Filters';
            app.HighpassFiltersButton = uibutton(app.UIFigure, 'push');
            app.HighpassFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @HighpassFiltersButtonPushed, true);
            app.HighpassFiltersButton.Text = 'Highpass Filters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = freq_filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end