classdef interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BicubicLabel              matlab.ui.control.Label
        BilinearLabel             matlab.ui.control.Label
        ApplyInterpolationButton  matlab.ui.control.Button
        SelectInputImageButton    matlab.ui.control.Button
        Image_4                   matlab.ui.control.Image
        Image_3                   matlab.ui.control.Image
        NearestNeighborsLabel     matlab.ui.control.Label
        InputImageLabel           matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        InterpolationLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NearestNeighborsButtonPushed(app, event)

        end

        % Callback function
        function BilinearButtonPushed(app, event)

        end

        % Callback function
        function BicubicButtonPushed(app, event)

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Callback function
        function NearestNeighborsButton_2Pushed(app, event)

        end

        % Callback function
        function BilinearButton_2Pushed(app, event)

        end

        % Callback function
        function BicubicButton_2Pushed(app, event)

        end

        % Button pushed function: SelectInputImageButton
        function SelectInputImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            fullpath = strcat(path, file);
            app.Image.ImageSource = fullpath;
        end

        % Button pushed function: ApplyInterpolationButton
        function ApplyInterpolationButtonPushed(app, event)
            I = imread(app.Image.ImageSource);

            nn = imresize(I,0.3,'nearest'); %shrinking by 30%
            app.Image_2.ImageSource = nn;

            bl = imresize(I,0.3,'bilinear');
            app.Image_3.ImageSource = bl;

            bc = imresize(I,0.3,'bicubic');
            app.Image_4.ImageSource = bc;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InterpolationLabel = uilabel(app.UIFigure);
            app.InterpolationLabel.Text = 'Interpolation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image3.jpg');
            app.Image_2 = uiimage(app.UIFigure);
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.NearestNeighborsLabel = uilabel(app.UIFigure);
            app.NearestNeighborsLabel.Text = 'Nearest Neighbors';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_4 = uiimage(app.UIFigure);
            app.SelectInputImageButton = uibutton(app.UIFigure, 'push');
            app.SelectInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputImageButtonPushed, true);
            app.SelectInputImageButton.Text = 'Select Input Image';
            app.ApplyInterpolationButton = uibutton(app.UIFigure, 'push');
            app.ApplyInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyInterpolationButtonPushed, true);
            app.ApplyInterpolationButton.Text = 'Apply Interpolation';
            app.BilinearLabel = uilabel(app.UIFigure);
            app.BilinearLabel.Text = 'Bilinear';
            app.BicubicLabel = uilabel(app.UIFigure);
            app.BicubicLabel.Text = 'Bicubic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpolation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end