classdef restoration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AdaptiveMedianFilterButton  matlab.ui.control.Button
        MedianFilterButton          matlab.ui.control.Button
        GeometricMeanFilterButton   matlab.ui.control.Button
        InputImageLabel             matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ImageRestorationLabel       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeometricMeanFilterButton
        function GeometricMeanFilterButtonPushed(app, event)
            mask_size = 3;
            im = imread(app.Image.ImageSource);
            mygrayimg = rgb2gray(im);
            gaus_noise = imnoise(mygrayimg, 'gaussian', 0.5, 0.03); %0.5 mean and variance 0.3
            gaus_noise=im2double(gaus_noise);
            geo_mask = ones(mask_size)/mask_size^2;
            geo_denoised = exp(conv2(log(1+gaus_noise),geo_mask,'same'))-1;
            
            subplot(1,3,1);imshow(mygrayimg);title("Original Image")
            subplot(1,3,2);imshow(gaus_noise);title("Gaussian Noise")
            subplot(1,3,3);imshow(geo_denoised);title("Geometric Mean Filter")
        end

        % Button pushed function: AdaptiveMedianFilterButton
        function AdaptiveMedianFilterButtonPushed(app, event)
            im = imread(app.Image.ImageSource);
            mygrayimg = rgb2gray(im);
            gaus_noise = imnoise(mygrayimg, "salt & pepper", 0.4); %0.5 mean and variance 0.3
            gaus_noise=im2double(gaus_noise);
            
            init_mask_size = 3;
            max_mask_size = 7;
            adap_med_cktb = adap_med_filter(gaus_noise,init_mask_size,max_mask_size);
            
            
            figure
            subplot(1,3,1);imshow(mygrayimg);title("Original Image")
            subplot(1,3,2),imshow(gaus_noise,[]),title('salt&pepper noise');
            subplot(1,3,3),imshow(adap_med_cktb,[]),title('adaptive median filter');
            
            
            function dest_image = adap_med_filter(src_image,init_size,max_size)
                [size_m,size_n] = size(src_image);
                dest_image = src_image;
                src_image = padarray(src_image,[max_size, max_size],'symmetric');
                
                for cur_dest_m = 1:size_m
                    for cur_dest_n=1:size_n
                        
                        cur_src_m = cur_dest_m+max_size;
                        cur_src_n = cur_dest_n+max_size;
                        cur_size = init_size;
                        
                        while cur_size <= max_size
                        % the while loop finds the appropriate mask_size
                            offset = floor(cur_size/2);
                            cur_mask = src_image(...
                                cur_src_m-offset:cur_src_m+offset...
                                ,cur_src_n-offset:cur_src_n+offset);
                            
                            cur_min = min(cur_mask(:));
                            cur_max = max(cur_mask(:));
                            cur_med = median(cur_mask(:));
            
                            if (cur_min<cur_med) && (cur_med < cur_max)
                                break;
                            end
                            
                            cur_size = cur_size + 2;
                        end % end of while loop
                                            
                        % 'good' pixel remains, while 'bad' pixel is replaced
                        % cur_min,cur_max and cur_med keep updated
                        if (cur_min<dest_image(cur_dest_m,cur_dest_n)) ...
                                && (dest_image(cur_dest_m,cur_dest_n)<cur_max)
                            dest_image(cur_dest_m,cur_dest_n) = ...
                                dest_image(cur_dest_m,cur_dest_n);
                        else
                            dest_image(cur_dest_m,cur_dest_n) = cur_med; 
                        end  
                    end
                end % end of for-for loop
            end
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            mask_size = 3;
            im = imread(app.Image.ImageSource);
            mygrayimg = rgb2gray(im);
            im = imnoise(mygrayimg,"salt & pepper",0.5);
            med_filter = @(mask)median(mask(:));
            med_0 = im;
            med_1 = nlfilter(med_0,[mask_size,mask_size],med_filter);
            med_2 = nlfilter(med_1,[mask_size,mask_size],med_filter);
            med_3 = nlfilter(med_2,[mask_size,mask_size],med_filter);
            
            figure
            subplot(3,2,1:2);imshow(mygrayimg);title("Original Image")
            subplot(3,2,3),imshow(med_0,[]),title('salt&pepper noise');
            subplot(3,2,4),imshow(med_1,[]),title('median filter for one time');
            subplot(3,2,5),imshow(med_2,[]),title('median filter for two times');
            subplot(3,2,6),imshow(med_3,[]),title('median filter for three times');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageRestorationLabel = uilabel(app.UIFigure);
            app.ImageRestorationLabel.Text = 'Image Restoration';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'onion.png';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.GeometricMeanFilterButton = uibutton(app.UIFigure, 'push');
            app.GeometricMeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricMeanFilterButtonPushed, true);
            app.GeometricMeanFilterButton.Text = 'Geometric Mean Filter';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.AdaptiveMedianFilterButton = uibutton(app.UIFigure, 'push');
            app.AdaptiveMedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveMedianFilterButtonPushed, true);
            app.AdaptiveMedianFilterButton.Text = 'Adaptive Median Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = restoration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end